classdef mcl_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonteCarloLocalizationUIFigure  matlab.ui.Figure
        SimulationAxis                  matlab.ui.control.UIAxes
        MonteCarloLocalizationLabel     matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        StartButton                     matlab.ui.control.Button
        xySpinnerLabel                  matlab.ui.control.Label
        xySpinner                       matlab.ui.control.Spinner
        thetaSpinnerLabel               matlab.ui.control.Label
        thetaSpinner                    matlab.ui.control.Spinner
        thetaSpinner_2Label             matlab.ui.control.Label
        thetaSpinner_2                  matlab.ui.control.Spinner
        rSpinnerLabel                   matlab.ui.control.Label
        rSpinner                        matlab.ui.control.Spinner
        UncertaintiesLabel              matlab.ui.control.Label
        MotionLabel                     matlab.ui.control.Label
        SensorLabel                     matlab.ui.control.Label
        DataAssociationSwitchLabel      matlab.ui.control.Label
        DataAssociationSwitch           matlab.ui.control.Switch
        xLabel                          matlab.ui.control.Label
        xField                          matlab.ui.control.NumericEditField
        yLabel                          matlab.ui.control.Label
        yField                          matlab.ui.control.NumericEditField
        thetaLabel                      matlab.ui.control.Label
        thetaField                      matlab.ui.control.NumericEditField
        ParticleFilterLabel             matlab.ui.control.Label
        EstimatedRobotPoseLabel         matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        CloseUpAxis                     matlab.ui.control.UIAxes
        xLabel_2                        matlab.ui.control.Label
        xField_2                        matlab.ui.control.NumericEditField
        yLabel_2                        matlab.ui.control.Label
        yField_2                        matlab.ui.control.NumericEditField
        thetaLabel_2                    matlab.ui.control.Label
        thetaField_2                    matlab.ui.control.NumericEditField
        ErrorLabel                      matlab.ui.control.Label
        OutlierDetectionLabel           matlab.ui.control.Label
        OutlierSwitch                   matlab.ui.control.Switch
        OutlierSpinner                  matlab.ui.control.Spinner
        MeasurementsLabel               matlab.ui.control.Label
        MeasurementField                matlab.ui.control.NumericEditField
        n_measurementsLabel             matlab.ui.control.Label
        IncorrectField                  matlab.ui.control.NumericEditField
        incorrectlyclassifiedLabel      matlab.ui.control.Label
        OutlierField                    matlab.ui.control.NumericEditField
        outliersdetectedLabel           matlab.ui.control.Label
        CheckBoxes                      matlab.ui.container.ButtonGroup
        CheckBox2                       matlab.ui.control.CheckBox
        CheckBox1                       matlab.ui.control.CheckBox
        CheckBox3                       matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        estimatedposeLabel              matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        groundtruthposeLabel            matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        odometryposeLabel               matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        correctassociationLabel         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        incorrectassociationLabel       matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        outlierLabel                    matlab.ui.control.Label
        settodefaultButton              matlab.ui.control.Button
        ParticlesSliderLabel            matlab.ui.control.Label
        ParticlesSlider                 matlab.ui.control.Slider
        ParticleEditField               matlab.ui.control.NumericEditField
        GlobalTrackingSwitch            matlab.ui.control.Switch
        ButtonGroup                     matlab.ui.container.ButtonGroup
        OffButton                       matlab.ui.control.RadioButton
        MultinomialButton               matlab.ui.control.RadioButton
        SystematicButton                matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        simulationfile % name of file containing simulation data
        mapfile % name of file containing landmark coordinates
    end
    
    methods (Access = private)
        
        function reset_app(app)
            cla(app.SimulationAxis);
            cla(app.CloseUpAxis);
            title(app.SimulationAxis, 'Simulation Time');
            app.StartButton.Enable = 'off';
            app.PauseButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            app.DropDown.Enable = 'on';
            app.xySpinner.Value = 0;
            app.thetaSpinner.Value = 0;
            app.thetaSpinner_2.Value = 0;
            app.rSpinner.Value = 0;
            app.OutlierSwitch.Enable = 'on';
            app.OutlierSwitch.Value = 'Off';
            app.OutlierSpinner.Visible = 'off';
            app.DataAssociationSwitch.Value = 'Off';
            app.GlobalTrackingSwitch.Enable = 'on';
            app.ParticlesSlider.Enable = 'on';
            app.ParticlesSlider.Value = 10;
            app.ParticleEditField.Enable = 'on';
            app.SystematicButton.Value = 1;
            app.ParticleEditField.Value = 10;
            app.MeasurementField.Value = 0;
            app.IncorrectField.Value = 0;
            app.OutlierField.Value = 0;
            app.xField.Value = 0;
            app.yField.Value = 0;
            app.thetaField.Value = 0;
            app.xField_2.Value = 0;
            app.yField_2.Value = 0;
            app.thetaField_2.Value = 0;
            app.CheckBox1.Value = false;
            app.CheckBox2.Value = false;
            app.CheckBox3.Value = false;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if strcmp(app.DropDown.Value, 'select_dataset')
                reset_app(app);
            else
                app.simulationfile = app.DropDown.Value;
                dataset_id = app.simulationfile(end-4);
                app.mapfile = strcat('map', dataset_id, '.txt');
                app.OutlierSwitch.Enable = 'on';
                % initialize GUI for selected dataset
                init_app(app, 'DataSets/', app.mapfile, str2num(dataset_id))
                % enable start button
                app.StartButton.Enable = 'on';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % enable stop button 
            app.PauseButton.Enable = 'on';
            app.StopButton.Enable = 'on';
            app.StartButton.Enable = 'off';
            app.DropDown.Enable = 'off';
            app.ParticlesSlider.Enable = 'off';
            app.GlobalTrackingSwitch.Enable = 'off';
            app.ParticleEditField.Enable = 'off';
            % run simulation
            run_simulation(app, 'DataSets/', app.simulationfile)
        end

        % Value changed function: xySpinner
        function xySpinnerValueChanged(app, event)
            global R
            % update motion model covariance matrix
            R(1,1) = app.xySpinner.Value;
            R(2,2) = app.xySpinner.Value;
        end

        % Value changed function: thetaSpinner
        function thetaSpinnerValueChanged(app, event)
            global R
            % update motion model covariance matrix
            R(3,3) = app.thetaSpinner.Value / 360 * (2*pi);
        end

        % Value changed function: thetaSpinner_2
        function thetaSpinner_2ValueChanged(app, event)
            global Q
            % update measurement update covariance matrix
            Q(2,2) = app.thetaSpinner_2.Value / 360 * (2*pi);
        end

        % Value changed function: rSpinner
        function rSpinnerValueChanged(app, event)
            global Q
            % update measurement update covariance matrix
            Q(1,1) = app.rSpinner.Value;  
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global stop_execution
            stop_execution = 1;
            reset_app(app);
            app.DropDown.Value = 'select_dataset';
            if strcmp(app.StopButton.Text, 'End')
                app.StopButton.Text = 'Stop';
                app.ErrorLabel.Text = 'Error';
                app.DropDown.Enable = 'on';
            end
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(5);
        end

        % Value changed function: CheckBox2
        function CheckBox2ValueChanged(app, event)
            global show_ground_truth
            show_ground_truth = app.CheckBox2.Value;
        end

        % Value changed function: CheckBox1
        function CheckBox1ValueChanged(app, event)
            global show_measurements
            show_measurements = app.CheckBox1.Value;
        end

        % Value changed function: CheckBox3
        function CheckBox3ValueChanged(app, event)
            global show_odometry
            show_odometry = app.CheckBox3.Value;
        end

        % Button pushed function: settodefaultButton
        function settodefaultButtonPushed(app, event)
            global default_R
            global default_Q
            global default_lambda_psi
            global R
            global Q
            global lambda_psi
            R = default_R;
            Q = default_Q;
            lambda_psi = default_lambda_psi;
            % display values in Spinners
            app.xySpinner.Value = sqrt(R(1, 1));
            app.thetaSpinner.Value = round(sqrt(R(3, 3)) / (2*pi) * 360);
            app.rSpinner.Value = sqrt(Q(1, 1));
            app.thetaSpinner_2.Value = round(sqrt(Q(2, 2)) / (2*pi) * 360);
        end

        % Value changed function: OutlierSwitch
        function OutlierSwitchValueChanged(app, event)
            global lambda_psi;
            global default_lambda_psi;
            if strcmp(app.OutlierSwitch.Value, 'On')
                app.OutlierSpinner.Visible = true;
                lambda_psi = default_lambda_psi;
                app.OutlierSpinner.Value = lambda_psi;
            else
                app.OutlierSpinner.Visible = false;
                lambda_psi = 0;
            end
        end

        % Value changed function: OutlierSpinner
        function OutlierSpinnerValueChanged(app, event)
            global lambda_psi
            lambda_psi = app.OutlierSpinner.Value;
            if lambda_psi == 0
                app.OutlierSpinner.Visible = false;
                app.OutlierSwitch.Value = 'Off';
            end
        end

        % Value changed function: DataAssociationSwitch
        function DataAssociationSwitchValueChanged(app, event)
            global DATA_ASSOCIATION
            DATA_ASSOCIATION = app.DataAssociationSwitch.Value;
        end

        % Value changed function: ParticlesSlider
        function ParticlesSliderValueChanged(app, event)
            global M
            M = round(app.ParticlesSlider.Value);
            app.ParticleEditField.Value = M;
        end

        % Value changed function: GlobalTrackingSwitch
        function GlobalTrackingSwitchValueChanged(app, event)
            global global_localization
            global lambda_psi;
            global default_lambda_psi;
            if strcmp(app.GlobalTrackingSwitch.Value, 'Global')
                global_localization = 1;
                app.OutlierSwitch.Value = 'Off';
                app.OutlierSpinner.Visible = false;
                lambda_psi = 0;
                app.OutlierSwitch.Enable = 'off';
            else
                global_localization = 0;
                app.OutlierSwitch.Value = 'On';
                app.OutlierSpinner.Visible = true;
                lambda_psi = default_lambda_psi;
                app.OutlierSpinner.Value = lambda_psi;
                app.OutlierSwitch.Enable = 'on';

                
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            global RESAMPLE_MODE
            sample_strategy = app.ButtonGroup.SelectedObject.Text;
            if strcmp(sample_strategy, 'Off')
                RESAMPLE_MODE = 0;
            elseif strcmp(sample_strategy, 'Multinomial')
                RESAMPLE_MODE = 1;
            else
                RESAMPLE_MODE = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MonteCarloLocalizationUIFigure = uifigure('Visible', 'off');
            app.MonteCarloLocalizationUIFigure.Name = 'MCL Localization';
            app.SimulationAxis = uiaxes(app.MonteCarloLocalizationUIFigure);
            title(app.SimulationAxis, 'Simulation Time')
            app.SimulationAxis.XTick = [];
            app.SimulationAxis.YTick = [];
            app.MonteCarloLocalizationLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.MonteCarloLocalizationLabel.Text = 'Monte Carlo Localization';
            app.DropDown = uidropdown(app.MonteCarloLocalizationUIFigure);
            app.DropDown.Items = {'Select Dataset', 'Dataset 1', 'Dataset 2'};
            app.DropDown.ItemsData = {'select_dataset', 'dataset1.txt', 'dataset2.txt'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'select_dataset';
            app.StartButton = uibutton(app.MonteCarloLocalizationUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.xySpinnerLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.xySpinnerLabel.Text = 'x, y';
            app.xySpinner = uispinner(app.MonteCarloLocalizationUIFigure);
            app.xySpinner.Step = 0.01;
            app.xySpinner.Limits = [0 10];
            app.xySpinner.ValueDisplayFormat = '%.2fm';
            app.xySpinner.ValueChangedFcn = createCallbackFcn(app, @xySpinnerValueChanged, true);
            app.thetaSpinnerLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.thetaSpinnerLabel.Text = 'theta';
            app.thetaSpinner = uispinner(app.MonteCarloLocalizationUIFigure);
            app.thetaSpinner.Limits = [0 360];
            app.thetaSpinner.ValueDisplayFormat = '%d°';
            app.thetaSpinner.ValueChangedFcn = createCallbackFcn(app, @thetaSpinnerValueChanged, true);
            app.thetaSpinner_2Label = uilabel(app.MonteCarloLocalizationUIFigure);
            app.thetaSpinner_2Label.Text = 'theta';
            app.thetaSpinner_2 = uispinner(app.MonteCarloLocalizationUIFigure);
            app.thetaSpinner_2.Limits = [0 360];
            app.thetaSpinner_2.ValueDisplayFormat = '%d°';
            app.thetaSpinner_2.ValueChangedFcn = createCallbackFcn(app, @thetaSpinner_2ValueChanged, true);
            app.rSpinnerLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.rSpinnerLabel.Text = 'r';
            app.rSpinner = uispinner(app.MonteCarloLocalizationUIFigure);
            app.rSpinner.Step = 0.01;
            app.rSpinner.Limits = [0 10];
            app.rSpinner.ValueDisplayFormat = '%.2fm';
            app.rSpinner.ValueChangedFcn = createCallbackFcn(app, @rSpinnerValueChanged, true);
            app.UncertaintiesLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.UncertaintiesLabel.Text = 'Uncertainties';
            app.MotionLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.MotionLabel.Text = 'Motion ';
            app.SensorLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.SensorLabel.Text = 'Sensor';
            app.DataAssociationSwitchLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.DataAssociationSwitchLabel.Text = 'Data Association';
            app.DataAssociationSwitch = uiswitch(app.MonteCarloLocalizationUIFigure, 'slider');
            app.DataAssociationSwitch.ValueChangedFcn = createCallbackFcn(app, @DataAssociationSwitchValueChanged, true);
            app.xLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.xLabel.Text = 'x';
            app.xField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.xField.ValueDisplayFormat = '%.2fm';
            app.yLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.yLabel.Text = 'y';
            app.yField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.yField.ValueDisplayFormat = '%.2fm';
            app.thetaLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.thetaLabel.Text = 'theta';
            app.thetaField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.thetaField.ValueDisplayFormat = '%d°';
            app.ParticleFilterLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.ParticleFilterLabel.Text = 'Particle Filter';
            app.EstimatedRobotPoseLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.EstimatedRobotPoseLabel.Text = 'Estimated Robot Pose';
            app.StopButton = uibutton(app.MonteCarloLocalizationUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.MonteCarloLocalizationUIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.CloseUpAxis = uiaxes(app.MonteCarloLocalizationUIFigure);
            app.CloseUpAxis.XLim = [-0.5 0.5];
            app.CloseUpAxis.YLim = [-0.5 0.5];
            app.CloseUpAxis.XTick = [];
            app.CloseUpAxis.XTickLabel = '';
            app.CloseUpAxis.YTick = [];
            app.CloseUpAxis.YTickLabel = '';
            app.xLabel_2 = uilabel(app.MonteCarloLocalizationUIFigure);
            app.xLabel_2.Text = 'x';
            app.xField_2 = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.xField_2.ValueDisplayFormat = '%.2fm';
            app.yLabel_2 = uilabel(app.MonteCarloLocalizationUIFigure);
            app.yLabel_2.Text = 'y';
            app.yField_2 = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.yField_2.ValueDisplayFormat = '%.2fm';
            app.thetaLabel_2 = uilabel(app.MonteCarloLocalizationUIFigure);
            app.thetaLabel_2.Text = 'theta';
            app.thetaField_2 = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.thetaField_2.ValueDisplayFormat = '%d°';
            app.ErrorLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.ErrorLabel.Text = 'Error';
            app.OutlierDetectionLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.OutlierDetectionLabel.Text = 'Outlier Detection';
            app.OutlierSwitch = uiswitch(app.MonteCarloLocalizationUIFigure, 'slider');
            app.OutlierSwitch.ValueChangedFcn = createCallbackFcn(app, @OutlierSwitchValueChanged, true);
            app.OutlierSpinner = uispinner(app.MonteCarloLocalizationUIFigure);
            app.OutlierSpinner.Step = 0.5;
            app.OutlierSpinner.Limits = [0 30];
            app.OutlierSpinner.ValueChangedFcn = createCallbackFcn(app, @OutlierSpinnerValueChanged, true);
            app.OutlierSpinner.Visible = 'off';
            app.OutlierSpinner.Value = 2;
            app.MeasurementsLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.MeasurementsLabel.Text = 'Measurements';
            app.MeasurementField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.MeasurementField.ValueDisplayFormat = '%d';
            app.n_measurementsLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.n_measurementsLabel.Text = 'number of measurements';
            app.IncorrectField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.IncorrectField.ValueDisplayFormat = '%d';
            app.incorrectlyclassifiedLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.incorrectlyclassifiedLabel.Text = 'incorrectly classified';
            app.OutlierField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.OutlierField.ValueDisplayFormat = '%d';
            app.outliersdetectedLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.outliersdetectedLabel.Text = 'outliers detected';
            app.CheckBoxes = uibuttongroup(app.MonteCarloLocalizationUIFigure);
            app.CheckBox2 = uicheckbox(app.CheckBoxes);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox2ValueChanged, true);
            app.CheckBox2.Text = 'show ground truth';
            app.CheckBox1 = uicheckbox(app.CheckBoxes);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = 'show measurements';
            app.CheckBox3 = uicheckbox(app.CheckBoxes);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox3ValueChanged, true);
            app.CheckBox3.Text = 'show odometry';
            app.Panel = uipanel(app.MonteCarloLocalizationUIFigure);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.estimatedposeLabel = uilabel(app.Panel);
            app.estimatedposeLabel.Text = 'estimated pose';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.groundtruthposeLabel = uilabel(app.Panel);
            app.groundtruthposeLabel.Text = 'ground truth pose';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '';
            app.odometryposeLabel = uilabel(app.Panel);
            app.odometryposeLabel.Text = 'odometry pose';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '';
            app.correctassociationLabel = uilabel(app.Panel);
            app.correctassociationLabel.Text = 'correct association';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '';
            app.incorrectassociationLabel = uilabel(app.Panel);
            app.incorrectassociationLabel.Text = 'incorrect association';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '';
            app.outlierLabel = uilabel(app.Panel);
            app.outlierLabel.Text = 'outlier';
            app.settodefaultButton = uibutton(app.MonteCarloLocalizationUIFigure, 'push');
            app.settodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @settodefaultButtonPushed, true);
            app.settodefaultButton.Text = 'set to default';
            app.ParticlesSliderLabel = uilabel(app.MonteCarloLocalizationUIFigure);
            app.ParticlesSliderLabel.Text = 'Particles';
            app.ParticlesSlider = uislider(app.MonteCarloLocalizationUIFigure);
            app.ParticlesSlider.Limits = [10 10000];
            app.ParticlesSlider.MajorTicks = [0 5000 10000];
            app.ParticlesSlider.MajorTickLabels = {'0', '5.000', '10.000'};
            app.ParticlesSlider.ValueChangedFcn = createCallbackFcn(app, @ParticlesSliderValueChanged, true);
            app.ParticlesSlider.MinorTicks = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000 5500 6000 6500 7000 7500 8000 8500 9000 9500 10000];
            app.ParticlesSlider.Value = 1000;
            app.ParticleEditField = uieditfield(app.MonteCarloLocalizationUIFigure, 'numeric');
            app.GlobalTrackingSwitch = uiswitch(app.MonteCarloLocalizationUIFigure, 'slider');
            app.GlobalTrackingSwitch.Items = {'Global', 'Tracking'};
            app.GlobalTrackingSwitch.ValueChangedFcn = createCallbackFcn(app, @GlobalTrackingSwitchValueChanged, true);
            app.GlobalTrackingSwitch.Value = 'Global';
            app.ButtonGroup = uibuttongroup(app.MonteCarloLocalizationUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.OffButton = uiradiobutton(app.ButtonGroup);
            app.OffButton.Text = 'Off';
            app.MultinomialButton = uiradiobutton(app.ButtonGroup);
            app.MultinomialButton.Text = 'Multinomial';
            app.SystematicButton = uiradiobutton(app.ButtonGroup);
            app.SystematicButton.Text = 'Systematic';
            app.SystematicButton.Value = true;
            % Show the figure after all components are created
            app.MonteCarloLocalizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mcl_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonteCarloLocalizationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonteCarloLocalizationUIFigure)
        end
    end
end